classdef IPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadButton                matlab.ui.control.Button
        PowerindexEditField       matlab.ui.control.NumericEditField
        PowerindexEditFieldLabel  matlab.ui.control.Label
        UpdateGraphButton         matlab.ui.control.Button
        TempindexEditField        matlab.ui.control.NumericEditField
        TempindexEditFieldLabel   matlab.ui.control.Label
        KIDEditField              matlab.ui.control.NumericEditField
        KIDEditFieldLabel         matlab.ui.control.Label
        plotsetupCheckBox         matlab.ui.control.CheckBox
        plotCBCheckBox            matlab.ui.control.CheckBox
        SWplotTLSCheckBox         matlab.ui.control.CheckBox
        C_GRSlider                matlab.ui.control.Slider
        C_GRSliderLabel           matlab.ui.control.Label
        Tau_qp10xSlider           matlab.ui.control.Slider
        Tau_qp10xSliderLabel      matlab.ui.control.Label
        gammaSlider               matlab.ui.control.Slider
        gammaLabel                matlab.ui.control.Label
        C_TLSSlider               matlab.ui.control.Slider
        C_TLSSliderLabel          matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UpdateGraphButton
        function UpdateGraphButtonPushed(app, event)
%% Part 1: Sec 1 | Plotting + save figure!
% REMEMBER to load 2D Data first!!
%This has side effect that it creates a figure if it is not yet present so needs to be after you create figure.
 % Swithches for user to play with ----------------------------------------
   SW_plotGR = 1;%[Binary] Plots the GR noise curve (Used)
   SW_plotTLS = 1;%[Binary] Plots polyfit in log log space (Used)
   SW_f2plotlinTLS = 1;%[Binary] plots the power law linear fit. Usually worse!
   SW_load2D = 1; %[Binary] Loads the data
   SW_plotsetup = 1;%[Binary] decides if you want to plot the found noise level
 %-------------------------------------------------------------------------
 if SW_load2D
load([ChipInfo_path FFTsubsubdir filesep 'NOISE_2D.mat'])
freq = NOISE(1).FFTnoise{1,1}(:,1);
end
 
 for kidn = kidn_iter % iterate over CKIDs.
    % IndexPsort is a vector of all the indices of powers for a specific
    % KID in Increasing order. 
    %power_iter = IndexPopt(kidn); %[Index] - Popt -Number of P_read values. For now I look at 1 power. 
    power_iter = f_power_iter(kidn); %[Index] - All P_read Number of P_read values. For now I look at 1 power. 
    
    for p = power_iter % This is the power iterator over a specific kid 
    
    f(p) = figure('WindowState','maximized');
    ax(p) = axes('XScale','log','YScale','linear');
    hold(ax(p),'on')
    Tcolors = colormapJetJB(14); % this has been set to 14 such that you always generate the set of colors such that you always obtain the same color for a high temp.
    
        for nT = Tbath_iter
            
            % Fit TLS ~2 - 20Hz
            
            linDataY_TLS = NOISE(p).FFTnoise{nT}(:,4);
            TLS_coof_lin{p,nT} = LLS_TLS_SdB(freq(TLSf_min_i:TLSf_max_i),linDataY_TLS(TLSf_min_i:TLSf_max_i),Model_oneoverf,C_v_TLS_0);
            S_noise_level(p,nT) = NOISE(p).FFTnoise{nT}(Snoise_i,4); % This is the setup noise level evaluated at 350000Hz.
            Scurr_noise_level = S_noise_level(p,nT) ;
            %Scurr_noise_level = dbtolin(-120)*(1/(4*NOISE(p).Ql(nT))^2);
            %Scurr_noise_level = 1E-25;
            % The anonymous function must be updated every time to
            % incorperate new function values
            Model_CB = @(C_v,fdata) C_v(1)./((1+power((2.*pi.*fdata.*C_v(2)),2).*(1)))+ Scurr_noise_level;

            if SW_plotsetup
                yline(lintodb(Scurr_noise_level))
            end
            %f2 = figure;
            dBlogDataX = log10(freq(TLSf_min_i:TLSf_max_i));
            dBlogDataY = 10.*log10(NOISE(p).FFTnoise{nT}(:,4));
            %plot(dBlogDataX,dBlogDataY(TLSf_min_i:TLSf_max_i))
            TLS_coof_dBlog{p,nT} = polyfit(dBlogDataX,dBlogDataY(TLSf_min_i:TLSf_max_i),1);
            C_TLS = power(10,(TLS_coof_dBlog{p,nT}(2)/10));% This is the constant extracted from the linear fit in log space.
            gamma_TLS = -(TLS_coof_dBlog{p,nT}(1)/10); % "
            TLS_coof_dBlog_to_lin{p,nT} = [C_TLS gamma_TLS];
            figure(f(p)) % Brings the plotting figure back into focus.
            
            % Fit GR-noise + TLS ~400Hz - 100KHz
            
            linDataY_CB = NOISE(p).FFTnoise{nT}(:,4);
            linDataY_CB = linDataY_CB-Model_oneoverf([C_TLS gamma_TLS],freq); % subtract the TLS line..
            
            %Fitting GR noise spectrum 
            CB_coof_lin{p,nT} = LLS_CB_SdB(freq(CBf_min_i:CBf_max_i),linDataY_CB(CBf_min_i:CBf_max_i),Model_CB,C_v_CB_0,CB_lb,CB_ub);
            
            % Finding intersects
            fTLS_curr = @(x)abs(Model_oneoverf(TLS_coof_dBlog_to_lin{p,nT},x));
            fGR_curr = @(x)Model_CB(CB_coof_lin{p,nT},x);
            [f_inter(p,nT),S_inter(p,nT)] = findintersect_SdB(fTLS_curr,fGR_curr,fguess);
            plot(f_inter(p,nT),lintodb(S_inter(p,nT)),'o','Color',Tcolors(nT,:),'LineWidth',1.5,'HandleVisibility','off');
            
            toplot = NOISE(p).FFTnoise{nT}(:,4) > 0; % makes sure you only plot positive data. As you approach low noise you data can become negative due to noise.
            plot(NOISE(p).FFTnoise{nT}(toplot,1),10*log10(NOISE(p).FFTnoise{nT}(toplot,4)),...
                    '-','color',Tcolors(nT,:),'LineWidth',1.5)
                
            
            xlabel('F [Hz]');ylabel('S_F/F^2 [dBc/Hz]')
            %Old: %xlim([0.5,1e5]);grid on;ylim([-220,-140])
            xlim([0.1,5e5]);grid on;ylim([-220,-140])
            title(append('KID#',string(NOISE(p).KIDnumber)," |Power ",string(NOISE(p).ReadPower),"dBm"));
            %legendTvalues{nT+1-min(Tbath_iter)} = append(sprintf('%1.3f',NOISE(p).Temperature(nT)),' K');
            legendTvalues{nT} = append(sprintf('%1.3f',NOISE(p).Temperature(nT)),' K');
            
            %plot(freq(toplot),10*log10(Model_oneoverf(TLS_coof_lin{p,nT},freq(toplot))),'--','Color',Tcolors(nT,:),'LineWidth',1)
            if SW_plotTLS
            plot(freq(toplot),Model_line(TLS_coof_dBlog{p,nT},log10(freq(toplot))),'-.','Color','black','LineWidth',1,'HandleVisibility','off')
            end %Plotting TLS lines
            plot(freq(CBf_min_i:CBf_max_i),10.*log10(linDataY_CB(CBf_min_i:CBf_max_i)),'--','Color',Tcolors(nT,:),'LineWidth',0.5,'HandleVisibility','off');%
            %compensated lines!
            
            
            % GR lines
            if SW_plotGR
                plot(freq(toplot),10.*log10(Model_CB(CB_coof_lin{p,nT},freq(toplot))),'-.','Color','black','LineWidth',1,'HandleVisibility','off');
                %plot(freq(toplot),10.*log10(Model_CB(CB_coof_lin_CB{p,nT},freq(toplot))),'-.','Color',Tcolors(nT,:),'LineWidth',1,'HandleVisibility','off');
            end % Plots GR lines if user switch is high.
            toplotTLSplusGR =lintodb(dbtolin(Model_line(TLS_coof_dBlog{p,nT},log10(freq(toplot)))) + Model_CB(CB_coof_lin{p,nT},freq(toplot)));
            % Final model lines
            plot(freq(toplot),toplotTLSplusGR,'-.','Color',Tcolors(nT,:),'LineWidth',1.5,'HandleVisibility','off');
            xline(1/(2*pi*abs(CB_coof_lin{p,nT}(2))),'--','Color',Tcolors(nT,:),'LineWidth',0.25,'HandleVisibility','off')
        end % End Bath temperature.
    legendTvalues = legendTvalues(~cellfun('isempty',legendTvalues));
    % this removes the zero entries from the legendTvalues celll.
    hTl1(kidn) = legend(legendTvalues,'location','eastOutside');
    hTl1(kidn).ItemTokenSize = [10,10];
    xline(freq(TLSf_min_i),'--','Color','c','LineWidth',1,'HandleVisibility','off')
    xline(freq(TLSf_max_i),'--','Color','c','LineWidth',1,'HandleVisibility','off')
    xline(freq(CBf_min_i),'--','Color','m','LineWidth',1,'HandleVisibility','off')
    xline(freq(CBf_max_i),'--','Color','m','LineWidth',1,'HandleVisibility','off')
    
    hold(ax(p),'off')
    export_path_graph = append('../../../Export_Figures_noGit/LT254_DA_figures/Part_1_GR/f1KID',string(kidn),'Pread',sprintf('%2.0f',NOISE(p).ReadPower),'.png');
    exportgraphics(ax(p),export_path_graph)  
    end % End power 
end % End #KID
disp('Part 1: Sec 1 - Done!')            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
figure
plot(app.UIAxes,[1 2],[2 5]);
            
%% Part 1: GR-noise fit. 
% This script does the main data analysis to how the interplay is between
% the TLS-noise and the GR-noise when we vary the temperature
% Author: Sietse de Boer
klier % Clearing...
% Importing Data
%
addpath('..\subroutines')
ChipInfo_path = ['..' filesep '..' filesep ]; %root path where data is, one higher than the scripts
FFTsubsubdir=['Data_LT254_Sietse' filesep 'LT254_Sietse_Chip11' filesep 'Noise_vs_T' filesep 'FFT' filesep '2D'];% This is where the
load([ChipInfo_path FFTsubsubdir filesep 'NOISE_2D.mat'])
freq = NOISE(1).FFTnoise{1,1}(:,1);
% User variables

% Variable (Change at your own risk!)

% TLS fit range 
TLSfitmin = 0.8;% [Hz]
TLSfitmax = 30;% [Hz]
[~,TLSf_min_i] = min(abs(freq-TLSfitmin)); % This gives the min index
[~,TLSf_max_i] = min(abs(freq-TLSfitmax)); % This gives the max index
C_v_TLS_0 = [(10^-15) 1.5];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5
C_v0_dBlog = [-150 1.5];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5
%TLS_lb = [10^-25 0.1];
%TLS_ub = [10^-8 2];
% Combined fit range: GR model + TLS noise model = approx GR model at high
% freq
CBfitmin = 1000;% [Hz]
CBfitmax = 150000;%  [Hz]
[~,CBf_min_i] = min(abs(freq-CBfitmin)); % "
[~,CBf_max_i] = min(abs(freq-CBfitmax)); % "
C_v_CB_0 = [(10^-18) 1.591549430918954e-06];% CTLS at 1 Hz , power with what  the power law decreases. TLS ~0.5

fguess = 2000; % [Hz] initial guess for the intersection point of the TLS and GR noise. 

% CB_lb = [10^-20 0.000000000000001];
% CB_ub = [10^-15 1];
CB_lb = [10^-20 (1/(2*pi*CBfitmax))];
CB_ub = [10^-15 (1/(2*pi*CBfitmin))];

%--Choosing your favorite KIDS,temperature and Reaout power(Moar Powa Baby)
kidn_iter = 2; %[Index] Select KID.
Tbath_iter = 8; %[Index] Number of Temperatures T_bath. Likely loop between 1:14;
SW_powerselector = 1 ;% choose power yourself or select Popt from file
power_selector = 7 ;% The index of the power that you want from low to high
%--End Choosing------------------------------------------------------------

%--Readout power selection ------------------------------------------------
if SW_powerselector % 1 case
f_power_iter = @(kidn)IndexPsort{kidn,1}(power_selector);
else % else
f_power_iter = @(kidn)IndexPopt(1,kidn);
end 
%-/Readout power selection -------------------------------------------------
tot_combi = length(concat_v(kidn_iter,IndexPsort,1));
f = gobjects(tot_combi,1);
ax = gobjects(tot_combi,1);
% Setup noise estimate
setup_noise_eval_freq = 350000;% [Hz]
[~,Snoise_i] = min(abs(freq-setup_noise_eval_freq)); % "

% Define fitting model 
% Here I will define the models that we will be using to fit the data. 
% This we will use to obtain a non-linear fit of the data. This gives heavy
% weight on the parts of the graph that are high on log log plot since here
% the difference is the biggest. 
Model_oneoverf = @(C_v,fdata)C_v(1).*power(fdata,-1*C_v(2)); %Model we use to fit C_v is the constants vector that we are trying to find.
Model_GR = @(C_v,fdata) C_v(1)./((1+power((2.*pi.*fdata.*C_v(2)),2).*(1))); %Model we use to fit C_v is the constants vector that we are trying to find.
Model_CB = @(C_v,fdata) C_v(1)./((1+power((2.*pi.*fdata.*C_v(2)),2).*(1)))+ Scurr_noise_level;
%Model_CB = @(C_v,fdata) C_TLS_corr.*power(fdata,-1*gamma_TLS)+C_v(1)./(1+power((2.*pi.*fdata.*C_v(2)),2)); %Model we use to fit C_v is the constants vector that we are trying to find.

%These models below are meant to be used in log space. Maybe later f = 10^x
% --> Later..
Model_oneoverf_dBlog = @(C_v,xdata)10*log10(C_v(1)) -10.*C_v(2).*xdata; %Model we use to fit C_v is the constants vector that we are trying to find.
%Model_GR_logspace = @(C_v,xdata) 10*log10(C(1))+10.*log10(1./(1+power((2.*pi.*power(10,x).*C(2)),2))); %Model we use to fit C_v is the constants vector that we are trying to find.

%Linear helper line
Model_line = @(C_v,xdata)C_v(1).*xdata+C_v(2);
diary('log.txt')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.C_TLSSliderLabel = uilabel(app.UIFigure);
            app.C_TLSSliderLabel.Text = 'C_TLS';
            app.C_TLSSlider = uislider(app.UIFigure);
            app.C_TLSSlider.Limits = [-160 -130];
            app.C_TLSSlider.Value = -130;
            app.gammaLabel = uilabel(app.UIFigure);
            app.gammaLabel.Text = 'gamma';
            app.gammaSlider = uislider(app.UIFigure);
            app.gammaSlider.Limits = [0.5 1.5];
            app.gammaSlider.Value = 0.5;
            app.Tau_qp10xSliderLabel = uilabel(app.UIFigure);
            app.Tau_qp10xSliderLabel.Text = 'Tau_qp(10^x)';
            app.Tau_qp10xSlider = uislider(app.UIFigure);
            app.Tau_qp10xSlider.Limits = [-6 -4];
            app.Tau_qp10xSlider.Value = -4;
            app.C_GRSliderLabel = uilabel(app.UIFigure);
            app.C_GRSliderLabel.Text = 'C_GR';
            app.C_GRSlider = uislider(app.UIFigure);
            app.C_GRSlider.Limits = [-180 -150];
            app.C_GRSlider.Value = -150;
            app.SWplotTLSCheckBox = uicheckbox(app.UIFigure);
            app.SWplotTLSCheckBox.Text = 'SW plot TLS';
            app.plotCBCheckBox = uicheckbox(app.UIFigure);
            app.plotCBCheckBox.Text = 'plot CB';
            app.plotsetupCheckBox = uicheckbox(app.UIFigure);
            app.plotsetupCheckBox.Text = 'plot setup';
            app.KIDEditFieldLabel = uilabel(app.UIFigure);
            app.KIDEditFieldLabel.Text = 'KID #';
            app.KIDEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempindexEditFieldLabel = uilabel(app.UIFigure);
            app.TempindexEditFieldLabel.Text = 'Temp index';
            app.TempindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateGraphButton = uibutton(app.UIFigure, 'push');
            app.UpdateGraphButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateGraphButtonPushed, true);
            app.UpdateGraphButton.Text = 'Update Graph';
            app.PowerindexEditFieldLabel = uilabel(app.UIFigure);
            app.PowerindexEditFieldLabel.Text = 'Power index';
            app.PowerindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end